classdef CosineDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AmplitudeVEditField       matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel  matlab.ui.control.Label
        FrequencyHzSlider         matlab.ui.control.Slider
        FrequencyHzSliderLabel    matlab.ui.control.Label
        MainAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function CalcEngine(app)
            syms 't' % declare t as a symbolic variable

            %% Parameters
            A0 = 1; % reference amplitude
            f0 = 1; % [Hz] reference frequency

            A = app.AmplitudeVEditField.Value; % [V] variable amplitude
            f = app.FrequencyHzSlider.Value; % [Hz]

            % reference cosine
            y0 = A0*cos(2*pi*f0*t); % [V]
            % variable cosine
            y = A*cos(2*pi*f*t); % [V]

            % plotting commands
            fplot(app.MainAxes, y0, [-1, 1], 'LineWidth', 2);
            hold(app.MainAxes, "on") % allow a second plot to be added without overwriting first plot
            fplot(app.MainAxes, y, [-1, 1], 'LineWidth', 2);
            hold(app.MainAxes,"off" );

            % gca is "get current axes"
            set(app.MainAxes, 'FontSize', 18)
            grid(app.MainAxes, "on");

            xlabel(app.MainAxes, '$t$ (s)', 'Interpreter','latex')
            ylabel(app.MainAxes, '$y$', 'Interpreter','latex')

            % Help the user distinguish datasets
            legend(app.MainAxes, {'Reference', 'Variable'})
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CalcEngine(app);
        end

        % Value changed function: FrequencyHzSlider
        function FrequencyHzSliderValueChanged(app, event)
            % value = app.FrequencyHzSlider.Value;
            CalcEngine(app);

        end

        % Value changed function: AmplitudeVEditField
        function AmplitudeVEditFieldValueChanged(app, event)
            % value = app.AmplitudeVEditField.Value;
            CalcEngine(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainAxes = uiaxes(app.UIFigure);
            title(app.MainAxes, 'Title')
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            zlabel(app.MainAxes, 'Z')
            app.FrequencyHzSliderLabel = uilabel(app.UIFigure);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.UIFigure);
            app.FrequencyHzSlider.Limits = [0 5];
            app.FrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSliderValueChanged, true);
            app.FrequencyHzSlider.Value = 1;
            app.AmplitudeVEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditFieldValueChanged, true);
            app.AmplitudeVEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CosineDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end